classdef advanced < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdvancedsettingsUIFigure     matlab.ui.Figure
        T1Label                      matlab.ui.control.Label
        T1msSpinner                  matlab.ui.control.Spinner
        msLabel                      matlab.ui.control.Label
        T1pcSpinner                  matlab.ui.control.Spinner
        basedonchannelLabel          matlab.ui.control.Label
        T1channelDropdown            matlab.ui.control.DropDown
        T2channelDropdown            matlab.ui.control.DropDown
        basedonchannelLabel_2        matlab.ui.control.Label
        T2pcSpinner                  matlab.ui.control.Spinner
        msLabel_2                    matlab.ui.control.Label
        T2msSpinner                  matlab.ui.control.Spinner
        T2Label                      matlab.ui.control.Label
        ProtondensitybasedonchannelDropDownLabel  matlab.ui.control.Label
        ProtondensitybasedonchannelDropDown  matlab.ui.control.DropDown
        ImagesizexdirectionmSpinnerLabel  matlab.ui.control.Label
        ImagesizexdirectionmSpinner  matlab.ui.control.Spinner
        OKButton                     matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        vals % Description
        callingapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingapp, vals)
            app.vals = vals;
            app.callingapp = callingapp;
            if isfield(vals, 'T1mid')
                app.T1msSpinner.Value = vals.T1mid * 1000;
            end
            if isfield(vals, 'T2mid')
                app.T2msSpinner.Value = vals.T2mid * 1000;
            end
            if isfield(vals, 'T1basedOn')
                app.T1channelDropdown.Value = vals.T1basedOn;
            end
            if isfield(vals, 'T1plusminus')
                app.T1pcSpinner.Value = vals.T1plusminus * 100;
            end
            if isfield(vals, 'T2basedOn')
                app.T2channelDropdown.Value = vals.T2basedOn;
            end
            if isfield(vals, 'T2plusminus')
                app.T2pcSpinner.Value = vals.T2plusminus * 100;
            end
            if isfield(vals, 'protondensitysource')
                app.ProtondensitybasedonchannelDropDown.Value = vals.protondensitysource;                
            end
            if isfield(vals, 'FOV')
                app.ImagesizexdirectionmSpinner.Value = app.vals.FOV(1);
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.vals.T1mid = app.T1msSpinner.Value * 1e-3;
            app.vals.T1basedOn = app.T1channelDropdown.Value;
            app.vals.T1plusminus = app.T1pcSpinner.Value/100;
            app.vals.T2mid = app.T2msSpinner.Value * 1e-3;
            app.vals.T2basedOn = app.T2channelDropdown.Value;
            app.vals.T2plusminus = app.T2pcSpinner.Value/100;
            app.vals.protondensitysource = app.ProtondensitybasedonchannelDropDown.Value;
            app.vals.FOV(1) = app.ImagesizexdirectionmSpinner.Value;
            app.callingapp.setVals(app.vals);
            close(app.AdvancedsettingsUIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close(app.AdvancedsettingsUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdvancedsettingsUIFigure = uifigure('Visible', 'off');
            app.AdvancedsettingsUIFigure.Name = 'Advanced settings';
            app.T1Label = uilabel(app.AdvancedsettingsUIFigure);
            app.T1Label.Text = 'T1';
            app.T1msSpinner = uispinner(app.AdvancedsettingsUIFigure);
            app.T1msSpinner.Value = 920;
            app.msLabel = uilabel(app.AdvancedsettingsUIFigure);
            app.msLabel.Text = 'ms    ±';
            app.T1pcSpinner = uispinner(app.AdvancedsettingsUIFigure);
            app.T1pcSpinner.Limits = [0 Inf];
            app.basedonchannelLabel = uilabel(app.AdvancedsettingsUIFigure);
            app.basedonchannelLabel.Text = '%,  based on channel';
            app.T1channelDropdown = uidropdown(app.AdvancedsettingsUIFigure);
            app.T1channelDropdown.Items = {'R', 'G', 'B'};
            app.T1channelDropdown.Value = 'R';
            app.T2channelDropdown = uidropdown(app.AdvancedsettingsUIFigure);
            app.T2channelDropdown.Items = {'R', 'G', 'B'};
            app.T2channelDropdown.Value = 'G';
            app.basedonchannelLabel_2 = uilabel(app.AdvancedsettingsUIFigure);
            app.basedonchannelLabel_2.Text = '%,  based on channel';
            app.T2pcSpinner = uispinner(app.AdvancedsettingsUIFigure);
            app.T2pcSpinner.Limits = [0 Inf];
            app.msLabel_2 = uilabel(app.AdvancedsettingsUIFigure);
            app.msLabel_2.Text = 'ms    ±';
            app.T2msSpinner = uispinner(app.AdvancedsettingsUIFigure);
            app.T2msSpinner.Value = 100;
            app.T2Label = uilabel(app.AdvancedsettingsUIFigure);
            app.T2Label.Text = 'T2';
            app.ProtondensitybasedonchannelDropDownLabel = uilabel(app.AdvancedsettingsUIFigure);
            app.ProtondensitybasedonchannelDropDownLabel.Text = 'Proton density based on channel';
            app.ProtondensitybasedonchannelDropDown = uidropdown(app.AdvancedsettingsUIFigure);
            app.ProtondensitybasedonchannelDropDown.Items = {'Sum of all', 'R', 'G', 'B'};
            app.ProtondensitybasedonchannelDropDown.Value = 'Sum of all';
            app.ImagesizexdirectionmSpinnerLabel = uilabel(app.AdvancedsettingsUIFigure);
            app.ImagesizexdirectionmSpinnerLabel.Text = {'Image size, x-direction (m)'; ''};
            app.ImagesizexdirectionmSpinner = uispinner(app.AdvancedsettingsUIFigure);
            app.ImagesizexdirectionmSpinner.Step = 0.01;
            app.ImagesizexdirectionmSpinner.Value = 0.2;
            app.OKButton = uibutton(app.AdvancedsettingsUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.AdvancedsettingsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.AdvancedsettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = advanced(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdvancedsettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdvancedsettingsUIFigure)
        end
    end
end