classdef mrigui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MRIRGBSimulatorUIFigure      matlab.ui.Figure
        RunButton                    matlab.ui.control.Button
        OrigImage                    matlab.ui.control.UIAxes
        InputdefinitionPanel         matlab.ui.container.Panel
        SelectinputfileButton        matlab.ui.control.Button
        InputfilenameLabel           matlab.ui.control.Label
        ResolutionShowLabel          matlab.ui.control.Label
        ResolutionLabel              matlab.ui.control.Label
        AdvancedButton               matlab.ui.control.Button
        ShowchannelDropDownLabel     matlab.ui.control.Label
        ShowchannelDropDown          matlab.ui.control.DropDown
        MRISettingsPanel             matlab.ui.container.Panel
        B0StrengthTSpinnerLabel      matlab.ui.control.Label
        B0StrengthTSpinner           matlab.ui.control.Spinner
        B1AmplitudeTSpinnerLabel     matlab.ui.control.Label
        B1AmplitudeTSpinner          matlab.ui.control.Spinner
        GradientstrengthTmEditFieldLabel  matlab.ui.control.Label
        GradientstrengthTmEditField  matlab.ui.control.EditField
        xSpinnerLabel                matlab.ui.control.Label
        xSpinner                     matlab.ui.control.Spinner
        ySpinnerLabel                matlab.ui.control.Label
        ySpinner                     matlab.ui.control.Spinner
        ResolutionpxLabel            matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        RseqTab                      matlab.ui.container.Tab
        AlphadegreesSpinnerLabel     matlab.ui.control.Label
        RAlphadegreesSpinner         matlab.ui.control.Spinner
        TEmsSpinnerLabel             matlab.ui.control.Label
        RTEmsSpinner                 matlab.ui.control.Spinner
        TRmsSpinnerLabel             matlab.ui.control.Label
        RTRmsSpinner                 matlab.ui.control.Spinner
        SequencetypeDropDownLabel    matlab.ui.control.Label
        RSequencetypeDropDown        matlab.ui.control.DropDown
        GseqTab                      matlab.ui.container.Tab
        TEmsSpinner_2Label           matlab.ui.control.Label
        GTEmsSpinner                 matlab.ui.control.Spinner
        TRmsSpinner_2Label           matlab.ui.control.Label
        GTRmsSpinner                 matlab.ui.control.Spinner
        SequencetypeDropDown_2Label  matlab.ui.control.Label
        GSequencetypeDropDown        matlab.ui.control.DropDown
        AlphadegreesSpinner_2Label   matlab.ui.control.Label
        GAlphadegreesSpinner         matlab.ui.control.Spinner
        BseqTab                      matlab.ui.container.Tab
        TEmsSpinner_3Label           matlab.ui.control.Label
        BTEmsSpinner                 matlab.ui.control.Spinner
        TRmsSpinner_3Label           matlab.ui.control.Label
        BTRmsSpinner                 matlab.ui.control.Spinner
        SequencetypeDropDown_3Label  matlab.ui.control.Label
        BSequencetypeDropDown        matlab.ui.control.DropDown
        AlphadegreesSpinner_3Label   matlab.ui.control.Label
        BAlphadegreesSpinner         matlab.ui.control.Spinner
        SequenceSwitch               matlab.ui.control.Switch
        OutputPanel                  matlab.ui.container.Panel
        RecreatedImage               matlab.ui.control.UIAxes
        OutputViewPanel              matlab.ui.container.Panel
        ShowchannelDropDown_2Label   matlab.ui.control.Label
        OutputShowDropDown           matlab.ui.control.DropDown
        RweightSliderLabel           matlab.ui.control.Label
        RweightSlider                matlab.ui.control.Slider
        GweightSliderLabel           matlab.ui.control.Label
        GweightSlider                matlab.ui.control.Slider
        BweightSliderLabel           matlab.ui.control.Label
        BweightSlider                matlab.ui.control.Slider
    end

    
    properties (Access = private)
        vals % Struct containing most relevant values
        Output % Output image
    end
    
    methods (Access = private)
        
        function  vals = readSettings(app, channel)
            vals = app.vals;
            vals = mrigui.setT1andT2(vals);
            vals.B0 = app.B0StrengthTSpinner.Value;
            vals.B1amplitude = app.B1AmplitudeTSpinner.Value * 1e-6;
            vals.Gradients = eval([ '[' app.GradientstrengthTmEditField.Value ']']);
            vals.TE = eval(['app.' channel 'TEmsSpinner.Value']) * 1e-3;
            vals.TR = eval(['app.' channel 'TRmsSpinner.Value']) * 1e-3;
            vals.px(1) = app.xSpinner.Value;
            vals.px(2) = app.ySpinner.Value;
            inputchannel = mrigui.channelNo(vals.protondensitysource);
            if inputchannel == 0
                vals.pdensity = vals.picbw;
            else
                vals.pdensity = double(vals.picrgb(:,:, inputchannel));
            end        
            if isequal(eval(['app.' channel 'SequencetypeDropDown.Value']), 'Spin-Echo')
                [vals.sequence, dummy, vals.fs] = spin_echo_sequence(vals);
                vals.postprocess = @spin_echo_post_process;
            else
                vals.alpha = eval(['app.' channel 'AlphadegreesSpinner.Value']) *2*pi/360;
                [vals.sequence, dummy, vals.fs] = gradient_echo_sequence(vals);                
                vals.postprocess = @gradient_echo_post_process;
            end
        end
        
        function EnableOutputViewSettings(app, value)%Value should be 'off' or 'on'            
            set(findall(app.OutputViewPanel, '-property', 'enable'), 'enable', value);
            
        end
        
        function EnableSequenceTabs(app, value)
            set(findall(app.GseqTab, '-property', 'enable'), 'enable', value);
            set(findall(app.BseqTab, '-property', 'enable'), 'enable', value);
        end
        
        function showOutput(app)
            showImg = app.Output;
            if size(showImg,3) == 3
                showImg(:,:,1) = showImg(:,:,1)*app.RweightSlider.Value;
                showImg(:,:,2) = showImg(:,:,2)*app.GweightSlider.Value;
                showImg(:,:,3) = showImg(:,:,3)*app.BweightSlider.Value;
                switch app.OutputShowDropDown.Value
                    case 'Black/White'
                        showImg = mean(showImg, 3);
                    case 'Red'
                        showImg = showImg(:,:,1);
                    case 'Green'
                        showImg = showImg(:,:,2);
                    case 'Blue'
                        showImg = showImg(:,:,3);
                    % if color, nothing to do
                end
            end
            imshow(showImg, 'Parent', app.RecreatedImage);
            daspect(app.RecreatedImage, [size(app.vals.picrgb, 1)/app.vals.px(2) size(app.vals.picrgb, 2)/app.vals.px(1) 1]);
        end
        
        function P = RunMRI(app, progress)
            p = mri_image(app.vals.pdensity + 1e-2, app.vals.sequence, app.vals, app.vals.fs, progress);
            P = app.vals.postprocess(p);            
            P = P/max(max(P));
        end
        
        function P = correctForProtonDensity(app, P)            
            ch = app.channelNo(app.vals.protondensitysource);
            proton = mean(P,3);
            if ch ~= 0
                proton = P(:,:,ch);
            end
            for i = 1:3
                if i ~= ch
                    P(:,:,i) = P(:,:,i)./(proton + 0.1);
                    P(:,:,i) = P(:,:,i)/max(max(P(:,:,i)));
                end
            end
            if ch == 0
                t1ch = mrigui.channelNo(app.vals.T1basedOn);
                t2ch = mrigui.channelNo(app.vals.T2basedOn);
                pch = 6 - t1ch - t2ch;
                P(:,:,pch) = proton - P(:, :, t1ch) - P(:, :, t2ch);
                P(:,:,pch) = P(:,:,pch) - min(min(P(:,:,pch)));
                P(:,:,pch) = P(:,:,pch)/max(max(P(:,:,pch)));                
            end
        end
    end
    
    methods (Access = private, Static)
         function vals = setT1andT2(vals)
            if vals.T1plusminus ~= 0
                chvals = double(squeeze(vals.picrgb(:,:,mrigui.channelNo(vals.T1basedOn))));
                chvals = 2*(chvals./255) - 1; 
                vals.T1 = vals.T1mid - chvals*vals.T1mid*vals.T1plusminus;
            else
                vals.T1 = vals.T1mid;
            end
            if vals.T2plusminus ~= 0
                chvals = double(squeeze(vals.picrgb(:,:,mrigui.channelNo(vals.T2basedOn))));
                chvals = 2*(chvals./255) - 1; 
                vals.T2 = vals.T2mid + chvals*vals.T2mid*vals.T2plusminus;
            else
                vals.T2 = vals.T2mid;
            end                  
         end
         
         function n = channelNo(name)
             n = 0;
             switch name
                 case 'R'
                     n = 1;
                 case 'G'
                     n = 2;
                 case 'B'
                     n = 3;
             end
         end
    end
    
    methods (Access = public)
        function setVals(app, vals)
            app.vals = vals;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.vals = defaultvalues();
            app.InputfilenameLabel.Text = app.vals.picname;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
             nloops = numel(app.vals.picbw);
            tic
            if isequal(app.SequenceSwitch.Value, 'Single sequence')
                app.vals = readSettings(app, 'R');
                [hWaitbar,progress]= ParForWaitbarCreateMH("Processing",nloops/1000);
                app.Output = app.RunMRI(progress);
                close(hWaitbar);
                app.EnableOutputViewSettings('off');
            else
                [hWaitbar,progress]= ParForWaitbarCreateMH("Processing",3*nloops/1000);app.vals = readSettings(app, 'R');
                P(:,:,1) = app.RunMRI(progress);
                app.vals = readSettings(app, 'G');
                P(:,:,2) = app.RunMRI(progress);
                app.vals = readSettings(app, 'B');
                P(:,:,3) = app.RunMRI(progress);
                close(hWaitbar);
                app.Output = app.correctForProtonDensity(P);                
                app.EnableOutputViewSettings('on');
            end
            toc
            app.showOutput();            
        end

        % Button pushed function: SelectinputfileButton
        function SelectinputfileButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.gif;*.bmp;*.jpeg', 'Image files (*.jpg;*.png;*.gif;*.bmp;*.jpeg)'; '*.*','All files (*.*)'});
            figure(app.MRIRGBSimulatorUIFigure);
            if ~isequal(0, file)
                app.InputfilenameLabel.Text = file;
                fpath = [path file];
                app.vals.picname = fpath;
                app.vals.picrgb = imread(fpath);
                app.vals.picbw = mean(app.vals.picrgb, 3);
                app.ResolutionShowLabel.Text = [num2str(size(app.vals.picbw, 2)) ' x ' num2str(size(app.vals.picbw, 1))];
                ShowchannelDropDownValueChanged(app, event);
                app.RunButton.Enable = 'on';
                app.InputfilenameLabel.Visible = true;
                app.ResolutionShowLabel.Visible = true;
            end
        end

        % Value changed function: RSequencetypeDropDown
        function RSequencetypeDropDownValueChanged(app, event)
            value = app.RSequencetypeDropDown.Value;
            if isequal(value, 'Spin-Echo')
                app.RAlphadegreesSpinner.Enable = false;
                app.AlphadegreesSpinnerLabel.Enable = false;
            else
                app.RAlphadegreesSpinner.Enable = true;
                app.AlphadegreesSpinnerLabel.Enable = true;                
            end
        end

        % Callback function
        function BlackandwhiteCheckBoxValueChanged(app, event)
            
        end

        % Button pushed function: AdvancedButton
        function AdvancedButtonPushed(app, event)
 
            dialog = advanced(app, app.vals);
            uiwait(dialog.AdvancedsettingsUIFigure);
        end

        % Value changed function: ShowchannelDropDown
        function ShowchannelDropDownValueChanged(app, event)
            value = app.ShowchannelDropDown.Value;
            switch value
                case 'Color'
                    imshow(app.vals.picrgb, 'Parent', app.OrigImage);
                case 'Black/White'
                    imshow(app.vals.picbw, [0 255], 'Parent', app.OrigImage);
                case 'Red'
                    imshow(squeeze(app.vals.picrgb(:,:,1)), [0 255], 'Parent', app.OrigImage);
                case 'Green'
                    imshow(squeeze(app.vals.picrgb(:,:,2)), [0 255], 'Parent', app.OrigImage);
                case 'Blue'
                    imshow(squeeze(app.vals.picrgb(:,:,3)), [0 255], 'Parent', app.OrigImage);
            end
        end

        % Callback function
        function RunRGBsequenceButtonPushed(app, event)
            app.vals = readSettings(app);
            nloops = numel(app.vals.picbw);
            %progress = uiprogressdlg(app.MRIRGBSimulatorUIFigure, 'Title', 'Processing', 'Message', 'Processing...');  
            %[updatefcn, closefcn] = progressbarParfor(app.MRIRGBSimulatorUIFigure, 3*nloops/1000, 'Title', 'Processing', 'Message', 'Processing...');
            progress = ParforProgressbar(3*nloops);
            tic            
            %Proton weight
            Proton = runSingleChannel(app, progress, 2.0, 0.014, 0.15*pi/2);
            %T1-weight
            PT1s = runSingleChannel(app, progress, 0.5, 0.014, pi/2);
            PT1 = PT1s./(Proton + 0.1);
            PT1 = PT1./max(max(PT1));
            P(:,:,1) = 1.5*PT1;
            %T2-weight
            PT2s = runSingleChannel(app, progress, 4.0, 0.090, 0.15*pi/2);            
            PT2 = PT2s./(Proton + 0.1);
            PT2 = PT2./max(max(PT2));
            P(:,:,2) = 0.85*PT2;
            P(:,:,3) = Proton;
            toc
            imshow(P, 'Parent', app.RecreatedImage);
            daspect(app.RecreatedImage, [size(app.vals.picrgb, 1)/app.vals.px(2) size(app.vals.picrgb, 2)/app.vals.px(1) 1]);
            %close(progress);
            %closefcn();
            delete(progress);
            %app.vals.p = p;
            %app.vals.P = P;           
        end

        % Value changed function: SequenceSwitch
        function SequenceSwitchValueChanged(app, event)
            value = isequal(app.SequenceSwitch.Value, "RGB sequences");
            if value                 
                app.GseqTab.Parent = app.TabGroup;
                app.BseqTab.Parent = app.TabGroup;
                app.RseqTab.Title = 'R seq.';
            else
                app.GseqTab.Parent = [];
                app.BseqTab.Parent = [];                
                app.RseqTab.Title = 'Sequence';
            end
            
        end

        % Value changed function: GSequencetypeDropDown
        function GSequencetypeDropDownValueChanged(app, event)
            value = app.GSequencetypeDropDown.Value;
            if isequal(value, 'Spin-Echo')
                app.GAlphadegreesSpinner.Enable = false;
                app.AlphadegreesSpinner_2Label.Enable = false;
            else
                app.GAlphadegreesSpinner.Enable = true;
                app.AlphadegreesSpinner_2Label.Enable = true;
            end
        end

        % Value changed function: BSequencetypeDropDown
        function BSequencetypeDropDownValueChanged(app, event)
            value = app.BSequencetypeDropDown.Value;
            if isequal(value, 'Spin-Echo')
                app.BAlphadegreesSpinner.Enable = false;
                app.AlphadegreesSpinner_2Label.Enable = false;
            else
                app.BAlphadegreesSpinner.Enable = true;
                app.AlphadegreesSpinner_2Label.Enable = true;
            end            
        end

        % Value changed function: OutputShowDropDown
        function OutputShowDropDownValueChanged(app, event)
            app.showOutput();            
        end

        % Value changed function: RweightSlider
        function RweightSliderValueChanged(app, event)
            app.showOutput();            
        end

        % Value changed function: GweightSlider
        function GweightSliderValueChanged(app, event)
            app.showOutput();            
        end

        % Value changed function: BweightSlider
        function BweightSliderValueChanged(app, event)
            app.showOutput();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MRIRGBSimulatorUIFigure = uifigure('Visible', 'off');
            app.MRIRGBSimulatorUIFigure.Name = 'MRI RGB Simulator';
            app.RunButton = uibutton(app.MRIRGBSimulatorUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.OrigImage = uiaxes(app.MRIRGBSimulatorUIFigure);
            title(app.OrigImage, '')
            xlabel(app.OrigImage, '')
            ylabel(app.OrigImage, '')
            app.OrigImage.XTick = [];
            app.OrigImage.YTick = [];
            app.InputdefinitionPanel = uipanel(app.MRIRGBSimulatorUIFigure);
            app.InputdefinitionPanel.Title = 'Input definition';
            app.SelectinputfileButton = uibutton(app.InputdefinitionPanel, 'push');
            app.SelectinputfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectinputfileButtonPushed, true);
            app.SelectinputfileButton.Text = 'Select input file';
            app.InputfilenameLabel = uilabel(app.InputdefinitionPanel);
            app.InputfilenameLabel.Visible = 'off';
            app.InputfilenameLabel.Text = 'Input file name';
            app.ResolutionShowLabel = uilabel(app.InputdefinitionPanel);
            app.ResolutionShowLabel.Visible = 'off';
            app.ResolutionShowLabel.Text = 'x by y';
            app.ResolutionLabel = uilabel(app.InputdefinitionPanel);
            app.ResolutionLabel.Text = 'Resolution';
            app.AdvancedButton = uibutton(app.InputdefinitionPanel, 'push');
            app.AdvancedButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedButtonPushed, true);
            app.AdvancedButton.Text = 'Advanced...';
            app.ShowchannelDropDownLabel = uilabel(app.InputdefinitionPanel);
            app.ShowchannelDropDownLabel.Text = 'Show channel';
            app.ShowchannelDropDown = uidropdown(app.InputdefinitionPanel);
            app.ShowchannelDropDown.Items = {'Color', 'Black/White', 'Red', 'Green', 'Blue'};
            app.ShowchannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowchannelDropDownValueChanged, true);
            app.ShowchannelDropDown.Value = 'Color';
            app.MRISettingsPanel = uipanel(app.MRIRGBSimulatorUIFigure);
            app.MRISettingsPanel.Title = 'MRI Settings';
            app.B0StrengthTSpinnerLabel = uilabel(app.MRISettingsPanel);
            app.B0StrengthTSpinnerLabel.Text = 'B0 Strength (T)';
            app.B0StrengthTSpinner = uispinner(app.MRISettingsPanel);
            app.B0StrengthTSpinner.Step = 0.1;
            app.B0StrengthTSpinner.Limits = [0 Inf];
            app.B0StrengthTSpinner.ValueDisplayFormat = '%.1f';
            app.B0StrengthTSpinner.Value = 1.5;
            app.B1AmplitudeTSpinnerLabel = uilabel(app.MRISettingsPanel);
            app.B1AmplitudeTSpinnerLabel.Text = 'B1 Amplitude (μT)';
            app.B1AmplitudeTSpinner = uispinner(app.MRISettingsPanel);
            app.B1AmplitudeTSpinner.Limits = [0 Inf];
            app.B1AmplitudeTSpinner.Value = 30;
            app.GradientstrengthTmEditFieldLabel = uilabel(app.MRISettingsPanel);
            app.GradientstrengthTmEditFieldLabel.Text = 'Gradient strength (T/m)';
            app.GradientstrengthTmEditField = uieditfield(app.MRISettingsPanel, 'text');
            app.GradientstrengthTmEditField.Value = '0.04,0.04,0.04';
            app.xSpinnerLabel = uilabel(app.MRISettingsPanel);
            app.xSpinnerLabel.Text = 'x';
            app.xSpinner = uispinner(app.MRISettingsPanel);
            app.xSpinner.Limits = [0 Inf];
            app.xSpinner.Value = 100;
            app.ySpinnerLabel = uilabel(app.MRISettingsPanel);
            app.ySpinnerLabel.Text = 'y';
            app.ySpinner = uispinner(app.MRISettingsPanel);
            app.ySpinner.Limits = [0 Inf];
            app.ySpinner.Value = 100;
            app.ResolutionpxLabel = uilabel(app.MRISettingsPanel);
            app.ResolutionpxLabel.Text = 'Resolution (px)';
            app.TabGroup = uitabgroup(app.MRISettingsPanel);
            app.RseqTab = uitab(app.TabGroup);
            app.RseqTab.Title = 'R seq.';
            app.AlphadegreesSpinnerLabel = uilabel(app.RseqTab);
            app.AlphadegreesSpinnerLabel.Text = 'Alpha (degrees)';
            app.RAlphadegreesSpinner = uispinner(app.RseqTab);
            app.RAlphadegreesSpinner.ValueDisplayFormat = '%.1f';
            app.RAlphadegreesSpinner.Value = 90;
            app.TEmsSpinnerLabel = uilabel(app.RseqTab);
            app.TEmsSpinnerLabel.Text = 'TE (ms)';
            app.RTEmsSpinner = uispinner(app.RseqTab);
            app.RTEmsSpinner.Limits = [0 Inf];
            app.RTEmsSpinner.Value = 14;
            app.TRmsSpinnerLabel = uilabel(app.RseqTab);
            app.TRmsSpinnerLabel.Text = 'TR (ms)';
            app.RTRmsSpinner = uispinner(app.RseqTab);
            app.RTRmsSpinner.Limits = [0 Inf];
            app.RTRmsSpinner.Value = 700;
            app.SequencetypeDropDownLabel = uilabel(app.RseqTab);
            app.SequencetypeDropDownLabel.Text = {'Sequence type'; ''};
            app.RSequencetypeDropDown = uidropdown(app.RseqTab);
            app.RSequencetypeDropDown.Items = {'Spin-Echo', 'Gradient-Echo'};
            app.RSequencetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @RSequencetypeDropDownValueChanged, true);
            app.RSequencetypeDropDown.Value = 'Gradient-Echo';
            app.GseqTab = uitab(app.TabGroup);
            app.GseqTab.Title = 'G seq.';
            app.TEmsSpinner_2Label = uilabel(app.GseqTab);
            app.TEmsSpinner_2Label.Text = 'TE (ms)';
            app.GTEmsSpinner = uispinner(app.GseqTab);
            app.GTEmsSpinner.Limits = [0 Inf];
            app.GTEmsSpinner.Value = 90;
            app.TRmsSpinner_2Label = uilabel(app.GseqTab);
            app.TRmsSpinner_2Label.Text = 'TR (ms)';
            app.GTRmsSpinner = uispinner(app.GseqTab);
            app.GTRmsSpinner.Limits = [0 Inf];
            app.GTRmsSpinner.Value = 4000;
            app.SequencetypeDropDown_2Label = uilabel(app.GseqTab);
            app.SequencetypeDropDown_2Label.Text = {'Sequence type'; ''};
            app.GSequencetypeDropDown = uidropdown(app.GseqTab);
            app.GSequencetypeDropDown.Items = {'Spin-Echo', 'Gradient-Echo'};
            app.GSequencetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GSequencetypeDropDownValueChanged, true);
            app.GSequencetypeDropDown.Value = 'Gradient-Echo';
            app.AlphadegreesSpinner_2Label = uilabel(app.GseqTab);
            app.AlphadegreesSpinner_2Label.Text = 'Alpha (degrees)';
            app.GAlphadegreesSpinner = uispinner(app.GseqTab);
            app.GAlphadegreesSpinner.ValueDisplayFormat = '%.1f';
            app.GAlphadegreesSpinner.Value = 15;
            app.BseqTab = uitab(app.TabGroup);
            app.BseqTab.Title = 'B seq.';
            app.TEmsSpinner_3Label = uilabel(app.BseqTab);
            app.TEmsSpinner_3Label.Text = 'TE (ms)';
            app.BTEmsSpinner = uispinner(app.BseqTab);
            app.BTEmsSpinner.Limits = [0 Inf];
            app.BTEmsSpinner.Value = 20;
            app.TRmsSpinner_3Label = uilabel(app.BseqTab);
            app.TRmsSpinner_3Label.Text = 'TR (ms)';
            app.BTRmsSpinner = uispinner(app.BseqTab);
            app.BTRmsSpinner.Limits = [0 Inf];
            app.BTRmsSpinner.Value = 2000;
            app.SequencetypeDropDown_3Label = uilabel(app.BseqTab);
            app.SequencetypeDropDown_3Label.Text = {'Sequence type'; ''};
            app.BSequencetypeDropDown = uidropdown(app.BseqTab);
            app.BSequencetypeDropDown.Items = {'Spin-Echo', 'Gradient-Echo'};
            app.BSequencetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @BSequencetypeDropDownValueChanged, true);
            app.BSequencetypeDropDown.Value = 'Gradient-Echo';
            app.AlphadegreesSpinner_3Label = uilabel(app.BseqTab);
            app.AlphadegreesSpinner_3Label.Text = 'Alpha (degrees)';
            app.BAlphadegreesSpinner = uispinner(app.BseqTab);
            app.BAlphadegreesSpinner.ValueDisplayFormat = '%.1f';
            app.BAlphadegreesSpinner.Value = 15;
            app.SequenceSwitch = uiswitch(app.MRISettingsPanel, 'slider');
            app.SequenceSwitch.Items = {'Single sequence', 'RGB sequences'};
            app.SequenceSwitch.ValueChangedFcn = createCallbackFcn(app, @SequenceSwitchValueChanged, true);
            app.SequenceSwitch.Value = 'RGB sequences';
            app.OutputPanel = uipanel(app.MRIRGBSimulatorUIFigure);
            app.OutputPanel.Title = 'Output';
            app.RecreatedImage = uiaxes(app.OutputPanel);
            title(app.RecreatedImage, '')
            xlabel(app.RecreatedImage, '')
            ylabel(app.RecreatedImage, '')
            app.RecreatedImage.XTick = [];
            app.RecreatedImage.YTick = [];
            app.OutputViewPanel = uipanel(app.OutputPanel);
            app.ShowchannelDropDown_2Label = uilabel(app.OutputViewPanel);
            app.ShowchannelDropDown_2Label.Text = 'Show channel:';
            app.OutputShowDropDown = uidropdown(app.OutputViewPanel);
            app.OutputShowDropDown.Items = {'Color', 'Black/White', 'Red', 'Green', 'Blue'};
            app.OutputShowDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputShowDropDownValueChanged, true);
            app.OutputShowDropDown.Value = 'Color';
            app.RweightSliderLabel = uilabel(app.OutputViewPanel);
            app.RweightSliderLabel.Text = 'R weight';
            app.RweightSlider = uislider(app.OutputViewPanel);
            app.RweightSlider.Limits = [0 2];
            app.RweightSlider.MajorTicks = [];
            app.RweightSlider.ValueChangedFcn = createCallbackFcn(app, @RweightSliderValueChanged, true);
            app.RweightSlider.MinorTicks = [];
            app.RweightSlider.Value = 1;
            app.GweightSliderLabel = uilabel(app.OutputViewPanel);
            app.GweightSliderLabel.Text = 'G weight';
            app.GweightSlider = uislider(app.OutputViewPanel);
            app.GweightSlider.Limits = [0 2];
            app.GweightSlider.MajorTicks = [];
            app.GweightSlider.ValueChangedFcn = createCallbackFcn(app, @GweightSliderValueChanged, true);
            app.GweightSlider.MinorTicks = [];
            app.GweightSlider.Value = 1;
            app.BweightSliderLabel = uilabel(app.OutputViewPanel);
            app.BweightSliderLabel.Text = 'B weight';
            app.BweightSlider = uislider(app.OutputViewPanel);
            app.BweightSlider.Limits = [0 2];
            app.BweightSlider.MajorTicks = [];
            app.BweightSlider.ValueChangedFcn = createCallbackFcn(app, @BweightSliderValueChanged, true);
            app.BweightSlider.MinorTicks = [];
            app.BweightSlider.Value = 1;
            % Show the figure after all components are created
            app.MRIRGBSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mrigui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MRIRGBSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MRIRGBSimulatorUIFigure)
        end
    end
end